classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        PropagateOrbitButton         matlab.ui.control.Button
        ActuatorsPanel               matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        BrushlessMotorsButton        matlab.ui.control.Button
        RWConfigurationButton        matlab.ui.control.Button
        AttitudeDeterminationPanel   matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        IMUsophCheckBox              matlab.ui.control.CheckBox
        ADS_DropDown                 matlab.ui.control.DropDown
        AutoTuningButton             matlab.ui.control.Button
        DisableAHRSCheckBox          matlab.ui.control.CheckBox
        Ekf_parameters_button        matlab.ui.control.Button
        sensors_button               matlab.ui.control.Button
        SimulationSettingsPanel      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        InitialTimeLabel_5           matlab.ui.control.Label
        InitialTimeLabel_4           matlab.ui.control.Label
        step_edit                    matlab.ui.control.NumericEditField
        InitialTimeLabel_3           matlab.ui.control.Label
        finalTime_edit               matlab.ui.control.NumericEditField
        initTime_edit                matlab.ui.control.NumericEditField
        text17_3                     matlab.ui.control.Label
        text17_2                     matlab.ui.control.Label
        text17                       matlab.ui.control.Label
        AttitudeControlPanel         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DisableAHRSfeedbackCheckBox  matlab.ui.control.CheckBox
        AutotuningButton             matlab.ui.control.Button
        controller_button            matlab.ui.control.Button
        controller_popupmenu         matlab.ui.control.DropDown
        ModelSettingsPanel           matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        MoreSettingsButton           matlab.ui.control.Button
        cubesat_button               matlab.ui.control.Button
        perturbation_button          matlab.ui.control.Button
        run_button                   matlab.ui.control.Button
        uibuttongroup7               matlab.ui.container.ButtonGroup
        GridLayout6                  matlab.ui.container.GridLayout
        compCost_text                matlab.ui.control.Label
        ascct_text                   matlab.ui.control.Label
        eulerint_text                matlab.ui.control.Label
        settime_text                 matlab.ui.control.Label
        text11                       matlab.ui.control.Label
        text10                       matlab.ui.control.Label
        text9                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        uibuttongroup1               matlab.ui.container.ButtonGroup
        GridLayout4                  matlab.ui.container.GridLayout
        CubeSatAnimationLabel        matlab.ui.control.Label
        time_sLabel                  matlab.ui.control.Label
        TLabel                       matlab.ui.control.Label
        graphic2_run_button          matlab.ui.control.Button
        graphic2_stop_button         matlab.ui.control.Button
        graphic2_axes                matlab.ui.control.UIAxes
        uipanel1                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        RMSE_Label                   matlab.ui.control.Label
        text2                        matlab.ui.control.Label
        graphic1_popupmenu           matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        text16                       matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        callSatParameters   % Handle to CubeSat settings window
        callDisturbances    % Handle to disturbance settings window
        simulation          % Struct with simulation results and time
        rotBody             % Graphics handles for body frame animation
    end
    
    properties (Access = public)
       simulationParameters % Simulation Parameters
       orbital_propagation  % Orbital propagation results
    end
    
    methods (Access = private)

        function timeStruct = getCurrentTimeSettings(app)
        %GETCURRENTTIMESETTINGS Reads and validates time parameters from the UI.
        %   Returns a struct containing:
        %       t0      - initial time [s]
        %       tf      - final time [s]
        %       step    - simulation step size [s]
        %       t       - time vector
        %       nSteps  - number of simulation steps
        
            timeStruct.t0   = app.initTime_edit.Value;
            timeStruct.tf   = app.finalTime_edit.Value;
            timeStruct.step = app.step_edit.Value;
        
            [timeStruct.t, timeStruct.nSteps] = app.validateData(timeStruct);
        end

        function [t, nSteps] = validateData(app, time)
        % VALIDATEDATA Validates simulation time parameters and generates the time vector.
        %
        %   This function ensures that the time span is logical and that the integration
        %   step won't lead to memory overflows or infinite loops.
        %
        %   INPUTS:
        %       app  : Handle to the main application (for uialert context).
        %       time : Struct containing .t0 (start), .tf (end), and .step (dt).
        %
        %   OUTPUTS:
        %       t      : Row vector of time points. Returns [] on error.
        %       nSteps : Total number of simulation points. Returns 0 on error.
        
            % Default outputs for failure cases
            t = [];
            nSteps = 0;
        
            try
                % 1. Logical and Numeric Validation
                if any(isnan([time.t0, time.tf, time.step]))
                    error('All time parameters must be valid numeric values.');
                end
                
                if time.t0 >= time.tf
                    error('Initial Time (%g) must be strictly lower than Final Time (%g).', time.t0, time.tf);
                end
                
                if time.step <= 0
                    error('Step size must be a positive value.');
                end
                
                if time.step > (time.tf - time.t0)
                    error('Step size exceeds the total simulation duration.');
                end
        
                % 2. Vector Generation
                t = time.t0 : time.step : time.tf;
                nSteps = numel(t);
        
                % 3. Memory Safeguard
                % Large vectors can freeze the UI during plot rendering.
                if nSteps > 1e6
                    warning('High resolution: %d steps may impact performance.', nSteps);
                end
        
            catch ME
                % Using uialert instead of errordlg for better integration with UIFigure
                uialert(app.UIFigure, ME.message, 'Validation Error', ...
                        'Icon', 'error', 'Modal', true);
            end
        end

        function [hx, lx, hy, ly, hz, lz] = plotBodyframe(app)
        %PLOTBODYFRAME Plot spacecraft body axes in the inertial frame.
        %   Uses the initial quaternion to rotate body unit vectors.
        
            %%% Import adsim utilities
            import adcsim.utils.*

            q = app.simulationParameters.initialValues.q0;
            ax = app.graphic2_axes;
            origin = [0 0 0];
        
            % Rotate body unit axes into inertial frame
            xB = quatRotation(q, [1 0 0]');
            yB = quatRotation(q, [0 1 0]');
            zB = quatRotation(q, [0 0 1]');
        
            % Plot axes
            [hx, lx] = app.plotVector(ax, origin, xB, 1, 'r--', 'X_B');
            [hy, ly] = app.plotVector(ax, origin, yB, 1, 'r--', 'Y_B');
            [hz, lz] = app.plotVector(ax, origin, zB, 1, 'r--', 'Z_B');
        
        end

        function updateBodyframe(~, B, hx, lx, hy, ly, hz, lz)
            % UPDATEBODYFRAME Updates the 3D graphics objects for the satellite's body frame.
            %
            %   This function modifies the properties of existing quiver3 (arrows) 
            %   and text (labels) objects to animate the satellite's rotation.
            %   By using 'set' instead of redrawing the plot, it ensures high 
            %   memory efficiency and smooth animation performance.
            %
            %   Inputs:
            %       ~  : Placeholder for the class object (ignored).
            %       B  : 3x3 matrix containing the rotated body axes unit vectors.
            %            Column 1: Rotated X-axis [x; y; z]
            %            Column 2: Rotated Y-axis [x; y; z]
            %            Column 3: Rotated Z-axis [x; y; z]
            %       hx : Graphic handle for the X-axis arrow (quiver3).
            %       lx : Graphic handle for the X-axis label (text).
            %       hy : Graphic handle for the Y-axis arrow (quiver3).
            %       ly : Graphic handle for the Y-axis label (text).
            %       hz : Graphic handle for the Z-axis arrow (quiver3).
            %       lz : Graphic handle for the Z-axis label (text).

            % Update X-axis arrow direction (U, V, W components) and label position
            set(hx, 'UData', B(1,1), 'VData', B(2,1), 'WData', B(3,1));      
            set(lx, 'Position', B(:,1));
            
            % Update Y-axis arrow direction and label position
            set(hy, 'UData', B(1,2), 'VData', B(2,2), 'WData', B(3,2));      
            set(ly, 'Position', B(:,2));
            
            % Update Z-axis arrow direction and label position
            set(hz, 'UData', B(1,3), 'VData', B(2,3), 'WData', B(3,3));      
            set(lz, 'Position', B(:,3));
        end

        function animation(app, x_state, hx, lx, hy, ly, hz, lz)
            %%% Import adsim utilities
            import adcsim.utils.*

            setappdata(0, 'StopLoop', false);
            set(app.graphic2_stop_button, 'ButtonPushedFcn', @(src,event) setappdata(0, 'StopLoop', true));
            
            total_steps = size(x_state, 2);
            num_frames = 300; 
            paso = max(1, round(total_steps / num_frames));
            
            for i = 1:paso:total_steps
                % Get current quaternion
                q_current = x_state(1:4, i);
                
                % Get rotation only in the frame to be drown
                B_current = quat2rot(q_current);
                
                % Update plot
                app.updateBodyframe(B_current, hx, lx, hy, ly, hz, lz);
                app.time_sLabel.Text = [num2str(app.simulationParameters.sim.t(i),'%.3f'),' s'];
                
                drawnow limitrate; 
                
                if getappdata(0, 'StopLoop')
                    break;
                end
            end
            
            app.graphic2_stop_button.Enable = "off";
            app.graphic2_run_button.Enable  = "on";
        end

        function base = mergeStructs(app, base, update)
        % MERGESTRUCTS Performs a recursive deep-merge of two structures.
        %
        %   BASE = MERGESTRUCTS(APP, BASE, UPDATE) takes the fields found 
        %   in UPDATE and applies them to the matching fields in BASE.
        %
        %   Key Features:
        %     - Preserves existing fields in BASE that are not present in UPDATE.
        %     - Recursively handles nested structures of any depth.
        %     - Filters out the 'i' identifier used for UI routing.

            % Get all field names from the incoming data
            f = fieldnames(update);
            
            for idx = 1:length(f)
                fieldName = f{idx};
                
                % Check if the current field is a nested structure
                if isstruct(update.(fieldName)) && isfield(base, fieldName)
                    
                    % Recursively merge sub-structures
                    base.(fieldName) = app.mergeStructs(base.(fieldName), update.(fieldName));
                    
                else
                    % Update terminal value, excluding the 'i' case identifier
                    if ~strcmp(fieldName, 'gui_identifier')
                        base.(fieldName) = update.(fieldName);
                    end
                end
            end
        end

        
        function plotEulerAngles(app, sim, time)
            % Clear the axes from any previous plots before starting.
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes, 'on');
            
            % It's good practice to calculate the angles once and store them.
            % Using 'ZYX' sequence is a common and explicit standard for Euler angles.
            angles_deg = 180/pi * quat2eul(sim.x(1:4,:)', 'ZYX')'; 
            
            % --- Step 1: Plot each angle and get its handle and color ---
            % Plot Roll angle and store its handle (p1) and the color MATLAB assigned to it.
            p1 = plot(app.UIAxes, time.t, angles_deg(1,:), 'LineWidth', 1.5);
            roll_color = p1.Color; 
            
            % Plot Pitch angle.
            p2 = plot(app.UIAxes, time.t, angles_deg(2,:), 'LineWidth', 1.5);
            pitch_color = p2.Color;
            
            % Plot Yaw angle.
            p3 = plot(app.UIAxes, time.t, angles_deg(3,:), 'LineWidth', 1.5);
            yaw_color = p3.Color;
            
            % --- Step 2: Draw settling time lines using the corresponding colors ---
            % Check if the settling time is a finite number before plotting. If it's NaN,
            % the line won't be drawn, preventing errors.
            if isfinite(sim.index.ts(1))
                l1 = line(app.UIAxes, [sim.index.ts(1), sim.index.ts(1)], app.UIAxes.YLim, 'LineStyle', '--', 'Color', roll_color, 'LineWidth', 1);
            else
                l1 = gobjects(0); % Create an empty graphics object if no line is drawn.
            end
            
            if isfinite(sim.index.ts(2))
                l2 = line(app.UIAxes, [sim.index.ts(2), sim.index.ts(2)], app.UIAxes.YLim, 'LineStyle', '--', 'Color', pitch_color, 'LineWidth', 1);
            else
                l2 = gobjects(0);
            end
            
            if isfinite(sim.index.ts(3))
                l3 = line(app.UIAxes, [sim.index.ts(3), sim.index.ts(3)], app.UIAxes.YLim, 'LineStyle', '--', 'Color', yaw_color, 'LineWidth', 1);
            else
                l3 = gobjects(0);
            end
            
            % --- Step 3: Build the legend explicitly and safely ---
            % Create a list of the graphics objects (handles) you want in the legend.
            legend_handles = [p1, p2, p3, l1, l2, l3];
            % Create a corresponding list of labels.
            legend_labels = {'Roll', 'Pitch', 'Yaw', 'ts_{roll}', 'ts_{pitch}', 'ts_{yaw}'};
            
            % Only include handles that are valid graphics objects. This automatically
            % skips labels for any settling time lines that were not drawn.
            legend(app.UIAxes, legend_handles(isgraphics(legend_handles)), legend_labels(isgraphics(legend_handles)));
            
            % --- Final Graph Configuration ---
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Euler Angles (deg)');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end
    end

    methods (Access = public)
        
        function [h1,h2] = plotVector(~, axesHandle, v0, v, scale, style, label)
        %PLOTVECTOR Plots a 3D vector with an optional label in the specified axes.
        %
        %   [H1, H2] = PLOTVECTOR(~, AXESHANGLE, V0, V, SCALE, STYLE, LABEL) draws a 3D vector
        %   in the specified AXESHANDLE. The vector starts from the scaled origin point
        %   (V0 * SCALE) and extends by the scaled vector (V * SCALE). It also adds a
        %   text label at the end of the vector.
        %
        %   Inputs:
        %     ~          - An ignored input argument. 
        %     axesHandle - The handle to the axes object where the vector will be plotted.
        %     v0         - A 1x3 numeric vector [x0, y0, z0] representing the base point
        %                  for the vector.
        %     v          - A 1x3 numeric vector [vx, vy, vz] representing the direction and
        %                  unscaled magnitude of the vector from its base point.
        %     scale      - A positive scalar value to proportionally scale both the starting
        %                  point and the vector components. The plotted vector will effectively
        %                  run from (V0 * SCALE) to ((V0 + V) * SCALE).
        %     style      - A character array specifying the line style and color for the vector
        %                  (e.g., 'r-', 'b--', 'k:'). The color part of 'style' is also used
        %                  for the text label's color.
        %     label      - A character array or string for the text label to be placed at
        %                  the end of the vector.
        %
        %   Outputs:
        %     h1    - The handle to the quiver3 object (the plotted vector).
        %     h2    - The handle to the text object (the vector's label).
        %
        %   Developed by Bespi123
            
            % Plot the 3D vector using quiver3.
            h1 = quiver3(axesHandle,v0(1)*scale, v0(2)*scale, v0(3)*scale, ...
                                v(1)*scale, v(2)*scale, v(3)*scale, style);
            % Add a text label at the end of the plotted vector.
            h2 = text(axesHandle,(v0(1)+v(1))*scale, (v0(2)+v(2))*scale, ...
                               (v0(3)+v(3))*scale, label,'Color',style(1));
            % Set the LineWidth property of the plotted vector (h1) to 2.
            set(h1,'Linewidth',2);
        end 

        function td_arr = getDisturbances(app, disturbances, time)
        % GETDISTURBANCES Calculates or retrieves the environmental disturbance torques.
        %
        % This function generates the disturbance torques acting on the CubeSat.
        % It can operate in two modes: a simple mathematical model (constant and 
        % sinusoidal torques) or a high-fidelity physical model using the Orekit 
        % orbital propagator to calculate gravity gradient, aerodynamic drag, 
        % and solar radiation pressure torques.
        %
        % Inputs:
        %   app          - Application structure containing overall simulation parameters.
        %   disturbances - Structure with disturbance settings and enable flags.
        %   time         - Structure containing the time vector (t) and steps (nSteps).
        %
        % Outputs:
        %   td_arr       - Structure containing the calculated torques:
        %                  .total          [3 x nSteps] Total torque array (Simple model)
        %                  .gravity_torque [3 x nSteps] Gravity gradient torque
        %                  .solar_torque   [3 x nSteps] Solar radiation torque
        %                  .torque.drag_torque [3 x nSteps] Aerodynamic drag torque
        
            %%% Extract time parameters
            t = time.t;      % Time vector
            n = time.nSteps; % Total number of simulation steps
            
            %%% Create simple constant disturbances array
            % Extract constant torque values [Nm]
            Tdx = disturbances.constant.Tdx;
            Tdy = disturbances.constant.Tdy;
            Tdz = disturbances.constant.Tdz;
            % Replicate to match the simulation time steps [3 x nSteps]
            Td_const_arr = repmat([Tdx, Tdy, Tdz], n, 1)';
            
            %%% Create simple variable (sinusoidal) disturbances array
            % Extract amplitude [Nm] and angular frequencies [rad/s]
            A  = disturbances.variable.A;
            w1 = disturbances.variable.w1;
            w2 = disturbances.variable.w2;
            w3 = disturbances.variable.w3;
            % Evaluate sinusoidal functions over the time vector [3 x nSteps]
            td_misc_arr = A * sin([w1*t; w2*t; w3*t]);
            
            %%% Mode Selector: Simple Model (1) vs High-Fidelity Propagator (Other)
            if disturbances.model_propagator.enable == 1
                
                % -- SIMPLE MATHEMATICAL MODEL --
                % Combine constant and variable torques based on their enable flags (2 = ON)
                if     (disturbances.constant.enable == 2) && (disturbances.variable.enable == 2)
                    td_arr.total = Td_const_arr + td_misc_arr;
                    
                elseif (disturbances.constant.enable == 2) && (disturbances.variable.enable == 1)
                    td_arr.total = Td_const_arr;
                    
                elseif (disturbances.constant.enable == 1) && (disturbances.variable.enable == 2)
                    td_arr.total = td_misc_arr;
                    
                else
                    % If both are disabled, set total torque to zero
                    td_arr.total = zeros(3, n);
                end
                
                % Initialize physical torques as zero arrays since the high-fidelity model is OFF
                td_arr.gravity_torque      = zeros(3, n); % Grav gradient torque [Nm]
                td_arr.torque.drag_torque  = zeros(3, n); % Aerodynamic drag torque [Nm]
                td_arr.solar_torque        = zeros(3, n); % Solar rad torque [Nm]
                
            else
                
                % -- HIGH-FIDELITY PHYSICAL MODEL --
                % Run the Orekit orbital propagator to obtain the satellite state over time
                app.orbital_propagation = core.runOrekitSimulation(app.simulationParameters);
                
                % Calculate specific environmental torques using specialized physical models
                %td_arr.gravity_torque      = gravity_torque_model(app.simulationParameters, app.orbital_propagation); % Grav gradient torque [Nm]
                %td_arr.torque.drag_torque  = drag_torque_model(app.simulationParameters, app.orbital_propagation);    % Aerodynamic drag torque [Nm]
                %td_arr.solar_torque        = solar_torque_model(app.simulationParameters, app.orbital_propagation);   % Solar rad torque [Nm]
                
            end
        end
        
        function importData(app, receivedData)
        % IMPORTDATA Synchronizes simulation parameters from external configuration windows.
        %
        % This method performs a deep-merge between 'receivedData' and the global 
        % 'app.simulationParameters' structure. It also handles specific UI 
        % refreshes for 3D visualizations when initial conditions change.
        %
        % Inputs:
        %   app          - Handle to the ADCS_program application.
        %   receivedData - Structure containing new values and a case identifier 'i'.
        
            %%% Import adsim utilities
            import adcsim.utils.*
    
            try
                % 1. Global Data Synchronization
                % Uses recursion to update only the fields sent by the sub-window,
                % preserving other parameters in the hierarchy.
                app.simulationParameters = app.mergeStructs(app.simulationParameters, receivedData);
        
                % 2. Subsystem-Specific Triggers
                % Case 1: Initial Conditions & Orbital Elements
                if receivedData.gui_identifier == 1
                    % Re-calculate the Rotation Matrix (DCM) from the updated initial quaternion
                    B = quat2rot(app.simulationParameters.initialValues.q0');
                    
                    % Update the 3D body frame handles for the animation preview
                    app.updateBodyframe(B, app.rotBody.hx, app.rotBody.lx, ...
                                            app.rotBody.hy, app.rotBody.ly, ...
                                            app.rotBody.hz, app.rotBody.lz);
                end
        
                % 3. User Feedback
                % Display a success notification using the modern App Designer alert system.
                uialert(app.UIFigure, 'Simulation parameters successfully updated.', ...
                        'Update Successful', 'Icon', 'success', 'Modal', false);
                
            catch ME
                % Error Management
                % If a field is mismatched or a utility is missing, alert the user.
                uialert(app.UIFigure, sprintf('Failed to import data: %s', ME.message), ...
                        'Import Error', 'Icon', 'error');
            end
        end

        % function importData(app, receivedData)
        %    %%% Import adsim utilities
        %    import adcsim.utils.*
        % 
        %    switch  receivedData.i
        %        case 1
        %             %%%Update parameters 
        %             app.simulationParameters.spacecraft.I  = receivedData.I;
        %             app.simulationParameters.spacecraft.mass       = receivedData.mass;      % kg
        %             app.simulationParameters.spacecraft.dragArea   = receivedData.dragArea;  % m^2
        %             app.simulationParameters.spacecraft.dragCoeff  = receivedData.dragCoeff; % unitless
        %             app.simulationParameters.spacecraft.srpArea    = receivedData.srpArea;   % m^2
        %             app.simulationParameters.spacecraft.srpCoeff   = receivedData.srpCoeff;  % unitless (Cr)
        %             %%%Update orbital parameters
        %             app.simulationParameters.initialOrbit.a    = receivedData.a;  % Semi-major axis (m)
        %             app.simulationParameters.initialOrbit.e    = receivedData.e;  % Eccentricity
        %             app.simulationParameters.initialOrbit.i    = receivedData.i;  % Inclination (rad)
        %             app.simulationParameters.initialOrbit.raan = receivedData.raan;  % Right Ascension of the Ascending Node (rad)
        %             app.simulationParameters.initialOrbit.pa   = receivedData.pa;    % Argument of Perigee (rad)
        %             app.simulationParameters.initialOrbit.ta   = receivedData.ta;    % True Anomaly (rad) 
        %             %%Inital values
        %             app.simulationParameters.initialValues.q0 = receivedData.q0';
        %             app.simulationParameters.initialValues.Wo = receivedData.Wo';  
        % 
        %             %%%Update animation
        %             % Get Rotation matrix
        %             B = quat2rot(receivedData.q0');
        %             app.updateBodyframe(B, app.rotBody.hx, app.rotBody.lx, app.rotBody.hy, app.rotBody.ly, app.rotBody.hz,app.rotBody.lz);
        % 
        %             %%%Show message
        %             msgbox("Initial conditions successfully modified","Success");
        % 
        %        case 2
        %             app.simulationParameters.disturbances.constant.Tdx = receivedData.constant.Tdx;
        %             app.simulationParameters.disturbances.constant.Tdy = receivedData.constant.Tdy;
        %             app.simulationParameters.disturbances.constant.Tdz = receivedData.constant.Tdz;
        %             app.simulationParameters.disturbances.constant.enable = receivedData.constant.enable;
        % 
        %             app.simulationParameters.disturbances.variable.A   = receivedData.variable.A;
        %             app.simulationParameters.disturbances.variable.w1  = receivedData.variable.w1;
        %             app.simulationParameters.disturbances.variable.w2  = receivedData.variable.w2;
        %             app.simulationParameters.disturbances.variable.w3  = receivedData.variable.w3;
        %             app.simulationParameters.disturbances.variable.enable = receivedData.variable.enable;
        % 
        %             msgbox("Disturbances parameter modified","Success");
        %        case 3
        %             app.simulationParameters.sensors.acc.bias = receivedData.acc.bias';
        %             app.simulationParameters.sensors.acc.std = receivedData.acc.std';
        % 
        %             app.simulationParameters.sensors.mag.bias = receivedData.mag.bias';
        %             app.simulationParameters.sensors.mag.std = receivedData.mag.std';
        % 
        %             app.simulationParameters.sensors.gyro.bias = receivedData.gyro.bias';
        %             app.simulationParameters.sensors.gyro.std = receivedData.gyro.std';
        % 
        %             app.simulationParameters.sensors.star.bias = receivedData.star.bias';
        %             app.simulationParameters.sensors.star.std = receivedData.star.std';
        %             app.simulationParameters.sensors.star.enable = receivedData.star.enable;
        %             app.simulationParameters.sensors.star.numberOfStars = receivedData.star.numberOfStars;
        % 
        %             msgbox("Sensor parameters modified","Success");
        %        case 4
        %             app.simulationParameters.setPoint.qd = receivedData.qd';
        %             %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
        %             app.simulationParameters.controller.feedback.Keye = receivedData.K_eye;
        %             app.simulationParameters.controller.feedback.Peye = receivedData.P_eye;
        %             msgbox("Controller parameters modified","Success");
        %        case 5
        %             app.simulationParameters.setPoint.qd = receivedData.qd';
        %             %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
        %             app.simulationParameters.controller.boskController.delta = receivedData.delta;
        %             app.simulationParameters.controller.boskController.gamma = receivedData.gamma;
        %             app.simulationParameters.controller.boskController.Umax  = receivedData.Umax;
        %             app.simulationParameters.controller.boskController.k0    = receivedData.k0;
        %             msgbox("Controller parameters modified","Success");
        % 
        %        case 6
        %             app.simulationParameters.ahrs.ekf.equalModel = receivedData.ekf.equalModel;
        %             if receivedData.ekf.equalModel == 0
        %                 app.simulationParameters.ahrs.ekf.acc_std  = receivedData.ekf.acc.std';
        %                 app.simulationParameters.ahrs.ekf.mag_std  = receivedData.ekf.mag.std';
        %                 app.simulationParameters.ahrs.ekf.gyro_std = receivedData.ekf.gyro.std';
        %                 app.simulationParameters.ahrs.ekf.star_std = receivedData.ekf.star.std';
        %             else                       
        %                 app.simulationParameters.ahrs.ekf.acc_std  = app.simulationParameters.sensors.acc.std;
        %                 app.simulationParameters.ahrs.ekf.mag_std  = app.simulationParameters.sensors.acc.std;
        %                 app.simulationParameters.ahrs.ekf.gyro_std = app.simulationParameters.sensors.acc.std;
        %                 app.simulationParameters.ahrs.ekf.star_std = app.simulationParameters.sensors.acc.std;
        %             end
        %             msgbox("EKF parameters modified","Success");
        %        case 7
        %            app.simulationParameters.rw.number = receivedData.rw.number;
        %            app.simulationParameters.rw.maxH   = receivedData.rw.maxH;
        %            app.simulationParameters.rw.beta1  = receivedData.rw.beta1;
        %            app.simulationParameters.rw.beta2  = receivedData.rw.beta2;
        %            app.simulationParameters.rw.W      = receivedData.rw.W;
        %            msgbox("RW configuration parameters modified","Success");
        %        case 8
        %            app.simulationParameters.control.Ts = receivedData.control.Ts;
        %            app.simulationParameters.sensors.gyro.Ts = receivedData.sensors.gyro.Ts;
        %            app.simulationParameters.sensors.attitude.Ts = receivedData.sensors.attitude.Ts;
        %            msgbox("Sample times modified","Success");
        %        case 9
        %            app.simulationParameters.rw.motor.kt   = receivedData.rw.motor.kt;
        %            app.simulationParameters.rw.motor.Jrw  = receivedData.rw.motor.Jrw;
        %            app.simulationParameters.rw.motor.b    = receivedData.rw.motor.b;
        %            app.simulationParameters.rw.motor.c    = receivedData.rw.motor.c;
        %            app.simulationParameters.rw.motor.L    = receivedData.rw.motor.L;
        %            app.simulationParameters.rw.motor.R    = receivedData.rw.motor.R; 
        %            app.simulationParameters.rw.motor.ke   = receivedData.rw.motor.ke; 
        % 
        %            %Reaction Wheels 
        %            app.simulationParameters.rw.motor.pid.kp =  receivedData.rw.motor.pid.kp;
        %            app.simulationParameters.rw.motor.pid.ki =  receivedData.rw.motor.pid.ki;
        %            app.simulationParameters.rw.motor.pid.kd =  receivedData.rw.motor.pid.kd;
        %            app.simulationParameters.rw.motor.pid.Nu =  receivedData.rw.motor.pid.Nu;
        % 
        %            msgbox("Brushless motor parameters modified","Success");
        %    end
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%Import orekit utilities
            import_orekit;

            %%%Load simulation parameters from simulation_Parameters.m file
            app.simulationParameters = simulation_Parameters();
            
            %%%Update interface
            app.controller_popupmenu.ValueIndex = app.simulationParameters.controller.selector;
            app.ADS_DropDown.Value              = app.simulationParameters.ahrs.flag;
            app.DisableAHRSCheckBox.Value       = ~app.simulationParameters.ahrs.enable;
            app.DisableAHRSfeedbackCheckBox.Value = ~app.simulationParameters.ahrs.enable_feedback;
            app.IMUsophCheckBox.Value =  ~app.simulationParameters.sensors_selector.imu.flag;
            
            %%%Time values
            app.finalTime_edit.Value = app.simulationParameters.sim.tf;
            app.initTime_edit.Value  = app.simulationParameters.sim.t0;
            app.step_edit.Value      = app.simulationParameters.sim.step;
            
            %%%Set plot
            hold(app.graphic2_axes,'on');
            
            axis(app.graphic2_axes, 'equal');
            app.graphic2_axes.XLim = [-1,1];
            app.graphic2_axes.YLim = [-1,1];
            app.graphic2_axes.ZLim = [-1,1];

            %%%Plot inertial Frame
            po = zeros(1,3);
            app.plotVector(app.graphic2_axes,po,[1,0,0],1,'b--','X_I');
            app.plotVector(app.graphic2_axes,po,[0,1,0],1,'b--','Y_I');
            app.plotVector(app.graphic2_axes,po,[0,0,1],1,'b--','Z_I');

            %%%Plot body Frame
            [hx, lx, hy, ly, hz,lz] = app.plotBodyframe();
            app.rotBody.hx = hx; app.rotBody.lx = lx;
            app.rotBody.hy = hy; app.rotBody.ly = ly;
            app.rotBody.hz = hz; app.rotBody.lz = lz;
        end

        % Button pushed function: cubesat_button
        function cubesat_buttonButtonPushed(app, event)
            app.callSatParameters = cubesatSettings(app, app.simulationParameters.spacecraft, app.simulationParameters.initialValues, app.simulationParameters.initialOrbit);
        end

        % Button pushed function: perturbation_button
        function perturbation_buttonButtonPushed(app, event)
            %%%Read Time values
            app.simulationParameters.sim = getCurrentTimeSettings(app);           
            app.simulationParameters.disturbances.torque.total = app.getDisturbances(app.simulationParameters.disturbances, app.simulationParameters.sim);
            
            %%%Open window
            app.callDisturbances = perturbationSettings(app,app.simulationParameters.sim,...
                                   app.simulationParameters.disturbances);
        end

        % Button pushed function: controller_button
        function controller_buttonButtonPushed(app, event)
            v = app.controller_popupmenu.ValueIndex;
           
            switch v
                case 1
                    feedbackSettings(app,app.simulationParameters.setPoint,app.simulationParameters.controller.feedback);
                case 2
                    boskovicSettings(app,app.simulationParameters.setPoint,app.simulationParameters.controller.boskController);
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            
            %%%Read time simulation Parameters
            app.simulationParameters.sim = getCurrentTimeSettings(app);
            if isempty(app.simulationParameters.sim.t)
                return; % Stop simulation if time validation fails   
            end

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, app.simulationParameters.sim);

            %%% 
            app.simulationParameters.controller.selector = app.controller_popupmenu.ValueIndex;
            app.simulationParameters.ahrs.flag = app.ADS_DropDown.Value;

            %%%RunSimulation
            [sim.x, sim.u, sim.torque, sim.x_est, sim.index, sim.sensors, sim.actuators, sim.error_flag] = core.simulation_rk4(app,sim.td.total, ...
                app.simulationParameters, app.simulationParameters.sim);
            
            %%%Send data to Matlab Workspace
            assignin('base', 'simulationResults', sim);

            %%%Update parameters
            app.Ekf_parameters_button.Enable = app.simulationParameters.ahrs.enable;
            app.AutoTuningButton.Enable = app.simulationParameters.ahrs.enable;

            %%%Reset selector
            app.graphic1_popupmenu.Enable = "on";
            app.graphic1_popupmenu.ValueIndex = 1;
            
            %%%Plot euler angles by default
            plotEulerAngles(app, sim, app.simulationParameters.sim);

            %%%turn into global
            app.simulation.results = sim;
            app.simulation.time = app.simulationParameters.sim.t;
            
            %%%Enable animation button
            app.graphic2_run_button.Enable = "on";
            
            %Update Controller performance parameters
            if sim.error_flag == 0
                app.settime_text.Text   = num2str(mean(sim.index.ts),'%.2E');
                app.eulerint_text.Text  = num2str(sim.index.eulerInt(end-1),'%.2E');
                app.ascct_text.Text     = num2str(sim.index.ascct(end-1),'%.2E');
                app.compCost_text.Text  = num2str(mean(sim.index.o(2:end)),'%.2E');
                if app.simulationParameters.ahrs.enable
                    app.RMSE_Label.Text = ['RMSE: ', num2str(sim.index.RMSE,'%.2E'), ' deg.'];
                end
            else
                app.settime_text.Text   = 'N/A';
                app.eulerint_text.Text  = 'N/A';
                app.ascct_text.Text     = 'N/A';
                app.compCost_text.Text  = 'N/A';

                warndlg('Unstable simulation', 'Simulation Error');
            end
        end

        % Value changed function: graphic1_popupmenu
        function graphic1_popupmenuValueChanged(app, event)
            value = app.graphic1_popupmenu.ValueIndex;
            num_rw = size(app.simulation.results.torque, 1);

            switch value
                case 1
                    plotEulerAngles(app, app.simulation.results, app.simulationParameters.sim.t);
                case 2
                    plot(app.UIAxes,app.simulationParameters.sim.t,app.simulation.results.x(1:4,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'q0','q1','q2','q3');
                    ylabel(app.UIAxes,'Quaternions');
                case 3
                    plot(app.UIAxes, app.simulationParameters.sim.t,app.simulation.results.x(5:7,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Angular Rates Error(rad/s)');
                case 4
                    plot(app.UIAxes, app.simulationParameters.sim.t,app.simulation.results.td)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Disturbances Torques(Nm)');
                case 5
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.u)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Torque Command(Nm)');
                case 6
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.torque','--')
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.actuators.torque_real')

                    xlabel(app.UIAxes,'Time(s)');
                    %num_rw = size(app.simulation.results.torque, 1);
                    legend_labels = cell(1, 2*num_rw); % Pre-asigna un cell array para eficiencia
                    for i = 1:2*num_rw
                        if i <= num_rw
                            legend_labels{i} = ['RW ', num2str(i)];
                        else
                            legend_labels{i} = ['Real torque RW ', num2str(i-num_rw)];
                        end
                    end
                    legend(app.UIAxes, legend_labels);
                    ylabel(app.UIAxes,'Control Torque(Nm)');
                    hold(app.UIAxes,'off');
                case 7
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.actuators.w_cmd','--');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.actuators.x_rw(1:2:end,:)');                 
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates (rad/s)');
                    
                    legend_labels = cell(1, 2*num_rw); % Pre-asigna un cell array para eficiencia
                    for i = 1:2*num_rw
                        if i <= num_rw 
                            legend_labels{i} = ['RW ', num2str(i)];
                        else
                            legend_labels{i} = ['Set Point RW', num2str(i-num_rw)];
                        end
                    end
                    legend(app.UIAxes, legend_labels);
                    hold(app.UIAxes,'off');
                case 8
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.actuators.x_rw(2:2:end,:)');
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates currents (A)');
                    legend_labels = cell(1, num_rw);
                    for i = 1:num_rw
                        legend_labels{i} = ['RW ', num2str(i)];
                    end
                    legend(app.UIAxes, legend_labels);
                        
                case 9
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.actuators.u_rw');
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'RW angular rates voltage (V)');
                    legend_labels = cell(1, num_rw);
                    for i = 1:num_rw
                        legend_labels{i} = ['RW ', num2str(i)];
                    end
                    legend(app.UIAxes, legend_labels);

                case 10
                    plot(app.UIAxes, app.simulationParameters.sim.t(1:end-1), app.simulation.results.index.eulerInt)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'EULERINT (rad.s)');
                    legend(app.UIAxes,'eulerint');
                case 11
                    plot(app.UIAxes, app.simulationParameters.sim.t, app.simulation.results.index.ascct)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'ASCCT (Nm)');
                    legend(app.UIAxes,'ascct');
               case 12
                    plot(app.UIAxes, app.simulationParameters.sim.t(1:end-1), app.simulation.results.index.o)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'Computational cost <o> (s)');
                    legend(app.UIAxes,'o');
               
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: graphic2_run_button
        function graphic2_run_buttonButtonPushed(app, event)
            %%%Enable stop animation button
            app.graphic2_stop_button.Enable = "on";
            app.graphic2_run_button.Enable  = "off";
            %%%Run animation
            app.animation(app.simulation.results.x, app.rotBody.hx, app.rotBody.lx, app.rotBody.hy, app.rotBody.ly, app.rotBody.hz, app.rotBody.lz);
          
        end

        % Button pushed function: graphic2_stop_button
        function graphic2_stop_buttonButtonPushed(app, event)
            %%%Enable stop animation button
            
        end

        % Button pushed function: sensors_button
        function sensors_buttonButtonPushed(app, event)
            sensorsSettings(app,app.simulationParameters.sensors);
        end

        % Value changed function: DisableAHRSCheckBox
        function DisableAHRSCheckBoxValueChanged(app, event)
            value = app.DisableAHRSCheckBox.Value;
            if value == 1
            %     app.Ekf_parameters_button.Enable = "off";
            %     app.sensors_button.Enable = "off";
                 app.simulationParameters.ahrs.enable = 0;
            else
            %     app.Ekf_parameters_button.Enable = "on";
            %     app.sensors_button.Enable = "on"; 
                 app.simulationParameters.ahrs.enable = 1;
            end
        end

        % Button pushed function: Ekf_parameters_button
        function Ekf_parameters_buttonButtonPushed(app, event)
            EKFevaluator(app,app.simulationParameters,app.simulation);
        end

        % Button pushed function: RWConfigurationButton
        function RWConfigurationButtonPushed(app, event)
            reactionWheelsConf(app,app.simulationParameters);
        end

        % Button pushed function: MoreSettingsButton
        function MoreSettingsButtonPushed(app, event)
            moreSettings(app,app.simulationParameters)
        end

        % Button pushed function: AutotuningButton
        function AutotuningButtonPushed(app, event)
            import optimization.*
            %%%Read controller selector
            v = app.controller_popupmenu.ValueIndex;
            app.simulationParameters.controller.selector = v;

            %%%Read time simulation Parameters
            app.simulationParameters.sim = getCurrentTimeSettings(app);
           
            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, app.simulationParameters.sim);
            
            % Optimization fun
            optimal_gains = optimize_gains_ga(app,sim.td, ...
                app.simulationParameters, app.simulationParameters.sim);

            if v == 1
                % Updates the parameters in the application with the optimal results.
                % This allows the user to immediately use the new gains.
                app.simulationParameters.controller.feedback.Peye = diag(optimal_gains(1:3));
                app.simulationParameters.controller.feedback.Keye = diag(optimal_gains(4:6));
            else
                % Updates the parameters in the application with the optimal results.
                % This allows the user to immediately use the new gains.
                app.simulationParameters.controller.boskController.delta = optimal_gains(1);
                app.simulationParameters.controller.boskController.gamma = optimal_gains(2); 
                app.simulationParameters.controller.boskController.k0    = optimal_gains(3);
            end
        end

        % Button pushed function: AutoTuningButton
        function AutoTuningButtonPushed(app, event)
            import optimization.*
            %%%Read time simulation Parameters
            app.simulationParameters.sim = getCurrentTimeSettings(app);

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, app.simulationParameters.sim);

            optimal_params = optimize_ekf_ga(app,sim.td, ...
                                           app.simulationParameters, app.simulationParameters.sim);

             % Update the parameters in the application with the found solution.
            app.simulationParameters.ahrs.ekf.equalModel = 0;
            app.simulationParameters.ahrs.ekf.gyro_std = optimal_params(1:3)';
            app.simulationParameters.ahrs.ekf.acc_std = optimal_params(4:6)';
            app.simulationParameters.ahrs.ekf.mag_std = optimal_params(7:9)';
            if app.simulationParameters.sensors.star.enable == 1
                app.simulationParameters.ahrs.ekf.star.std = optimal_params(10:12)';
            end

        end

        % Button pushed function: BrushlessMotorsButton
        function BrushlessMotorsButtonPushed(app, event)
            reactionWheels_parameters(app,app.simulationParameters);
        end

        % Button pushed function: PropagateOrbitButton
        function PropagateOrbitButtonPushed(app, event)
            app.orbital_propagation = core.runOrekitSimulation(app.simulationParameters);
            orbit_propagation(app, app.orbital_propagation);
        end

        % Value changed function: DisableAHRSfeedbackCheckBox
        function DisableAHRSfeedbackCheckBoxValueChanged(app, event)
            value = app.DisableAHRSfeedbackCheckBox.Value;
            if value == 1
                 app.simulationParameters.ahrs.enable_feedback = 0;
            else
                 app.simulationParameters.ahrs.enable_feedback = 1;
            end
        end

        % Value changed function: IMUsophCheckBox
        function IMUsophCheckBoxValueChanged(app, event)
            value = app.IMUsophCheckBox.Value;
            if value == 1
                 app.simulationParameters.sensors_selector.imu.flag = 0;
            else
                 app.simulationParameters.sensors_selector.imu.flag = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.text16 = uilabel(app.GridLayout);
            app.text16.Tag = 'text16';
            app.text16.Text = 'ADCSim: A CubeSat simulator';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Graph';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Simulation Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.graphic1_popupmenu = uidropdown(app.GridLayout7);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Torque command', 'Control Torque', 'Reaction Wheels rates', 'Reaction Wheels currents', 'Reaction Wheel voltages', 'EULERINT', 'ASCCT', 'O'};
            app.graphic1_popupmenu.ValueChangedFcn = createCallbackFcn(app, @graphic1_popupmenuValueChanged, true);
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Enable = 'off';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.GridLayout7);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.RMSE_Label = uilabel(app.GridLayout7);
            app.RMSE_Label.Text = 'RMSE: 0.00 deg';
            app.uibuttongroup1 = uibuttongroup(app.GridLayout);
            app.uibuttongroup1.Title = 'Animation';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.GridLayout4 = uigridlayout(app.uibuttongroup1);
            app.graphic2_axes = uiaxes(app.GridLayout4);
            xlabel(app.graphic2_axes, 'X')
            ylabel(app.graphic2_axes, 'Y')
            zlabel(app.graphic2_axes, 'Z')
            app.graphic2_axes.XLim = [-1 1];
            app.graphic2_axes.YLim = [-1 1];
            app.graphic2_axes.ZLim = [-1 1];
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_stop_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_stop_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_stop_buttonButtonPushed, true);
            app.graphic2_stop_button.Tag = 'graphic2_button';
            app.graphic2_stop_button.Enable = 'off';
            app.graphic2_stop_button.Text = 'Stop';
            app.graphic2_run_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_run_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_run_buttonButtonPushed, true);
            app.graphic2_run_button.Tag = 'graphic2_button';
            app.graphic2_run_button.Enable = 'off';
            app.graphic2_run_button.Text = 'Run';
            app.TLabel = uilabel(app.GridLayout4);
            app.TLabel.Text = 'T: ';
            app.time_sLabel = uilabel(app.GridLayout4);
            app.time_sLabel.Text = '0.00 s';
            app.CubeSatAnimationLabel = uilabel(app.GridLayout4);
            app.CubeSatAnimationLabel.Text = 'CubeSat Animation';
            app.uibuttongroup7 = uibuttongroup(app.GridLayout);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.GridLayout6 = uigridlayout(app.uibuttongroup7);
            app.text8 = uilabel(app.GridLayout6);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.GridLayout6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.GridLayout6);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.GridLayout6);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.GridLayout6);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.GridLayout6);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.run_button = uibutton(app.GridLayout, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run ADCS Simulation';
            app.ModelSettingsPanel = uipanel(app.GridLayout);
            app.ModelSettingsPanel.Title = 'Model Settings';
            app.GridLayout2 = uigridlayout(app.ModelSettingsPanel);
            app.perturbation_button = uibutton(app.GridLayout2, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_buttonButtonPushed, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.cubesat_button = uibutton(app.GridLayout2, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_buttonButtonPushed, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.MoreSettingsButton = uibutton(app.GridLayout2, 'push');
            app.MoreSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @MoreSettingsButtonPushed, true);
            app.MoreSettingsButton.Text = 'More Settings';
            app.AttitudeControlPanel = uipanel(app.GridLayout);
            app.AttitudeControlPanel.Title = 'Attitude Control';
            app.GridLayout3 = uigridlayout(app.AttitudeControlPanel);
            app.controller_popupmenu = uidropdown(app.GridLayout3);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.GridLayout3, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_buttonButtonPushed, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.AutotuningButton = uibutton(app.GridLayout3, 'push');
            app.AutotuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutotuningButtonPushed, true);
            app.AutotuningButton.Text = 'Auto tuning';
            app.DisableAHRSfeedbackCheckBox = uicheckbox(app.GridLayout3);
            app.DisableAHRSfeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableAHRSfeedbackCheckBoxValueChanged, true);
            app.DisableAHRSfeedbackCheckBox.Text = 'Disable AHRS feedback';
            app.SimulationSettingsPanel = uipanel(app.GridLayout);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout5 = uigridlayout(app.SimulationSettingsPanel);
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text17_2 = uilabel(app.GridLayout5);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 's';
            app.text17_3 = uilabel(app.GridLayout5);
            app.text17_3.Tag = 'text17';
            app.text17_3.Text = 's';
            app.initTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.initTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.finalTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit.Value = 30;
            app.InitialTimeLabel_3 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_3.Text = 'Step:';
            app.step_edit = uieditfield(app.GridLayout5, 'numeric');
            app.step_edit.ValueDisplayFormat = '%.E';
            app.step_edit.Value = 0.0001;
            app.InitialTimeLabel_4 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_4.Text = 'Final time:';
            app.InitialTimeLabel_5 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_5.Text = 'Initial time:';
            app.AttitudeDeterminationPanel = uipanel(app.GridLayout);
            app.AttitudeDeterminationPanel.Title = 'Attitude Determination';
            app.GridLayout2_2 = uigridlayout(app.AttitudeDeterminationPanel);
            app.sensors_button = uibutton(app.GridLayout2_2, 'push');
            app.sensors_button.ButtonPushedFcn = createCallbackFcn(app, @sensors_buttonButtonPushed, true);
            app.sensors_button.Tag = 'perturbation_button';
            app.sensors_button.Text = 'Sensor Models';
            app.Ekf_parameters_button = uibutton(app.GridLayout2_2, 'push');
            app.Ekf_parameters_button.ButtonPushedFcn = createCallbackFcn(app, @Ekf_parameters_buttonButtonPushed, true);
            app.Ekf_parameters_button.Tag = 'cubesat_button';
            app.Ekf_parameters_button.Text = 'Parameters';
            app.DisableAHRSCheckBox = uicheckbox(app.GridLayout2_2);
            app.DisableAHRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableAHRSCheckBoxValueChanged, true);
            app.DisableAHRSCheckBox.Text = 'Disable AHRS';
            app.AutoTuningButton = uibutton(app.GridLayout2_2, 'push');
            app.AutoTuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuningButtonPushed, true);
            app.AutoTuningButton.Text = 'Auto Tuning';
            app.ADS_DropDown = uidropdown(app.GridLayout2_2);
            app.ADS_DropDown.Items = {'QUEST', 'REQUEST', 'MAHONY', 'MADGWICK', 'EKF', 'UKF', 'CKF'};
            app.ADS_DropDown.Value = 'MADGWICK';
            app.IMUsophCheckBox = uicheckbox(app.GridLayout2_2);
            app.IMUsophCheckBox.ValueChangedFcn = createCallbackFcn(app, @IMUsophCheckBoxValueChanged, true);
            app.IMUsophCheckBox.Text = 'IMU soph';
            app.ActuatorsPanel = uipanel(app.GridLayout);
            app.ActuatorsPanel.Title = 'Actuators';
            app.GridLayout8 = uigridlayout(app.ActuatorsPanel);
            app.RWConfigurationButton = uibutton(app.GridLayout8, 'push');
            app.RWConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RWConfigurationButtonPushed, true);
            app.RWConfigurationButton.Text = 'RW Configuration';
            app.BrushlessMotorsButton = uibutton(app.GridLayout8, 'push');
            app.BrushlessMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @BrushlessMotorsButtonPushed, true);
            app.BrushlessMotorsButton.Text = 'Brushless Motors';
            app.PropagateOrbitButton = uibutton(app.GridLayout, 'push');
            app.PropagateOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateOrbitButtonPushed, true);
            app.PropagateOrbitButton.Text = 'Propagate Orbit';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.GridLayout5.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end