classdef cubesatSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout3  matlab.ui.container.GridLayout
        uipanel2     matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        roll_edit    matlab.ui.control.NumericEditField
        ratez_edit   matlab.ui.control.NumericEditField
        ratey_edit   matlab.ui.control.NumericEditField
        ratex_edit   matlab.ui.control.NumericEditField
        yaw_edit     matlab.ui.control.NumericEditField
        pitch_edit   matlab.ui.control.NumericEditField
        text11       matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        Izy_edit     matlab.ui.control.NumericEditField
        Izx_edit     matlab.ui.control.NumericEditField
        Iyz_edit     matlab.ui.control.NumericEditField
        Iyx_edit     matlab.ui.control.NumericEditField
        Ixz_edit     matlab.ui.control.NumericEditField
        Ixy_edit     matlab.ui.control.NumericEditField
        Izz_edit     matlab.ui.control.NumericEditField
        Iyy_edit     matlab.ui.control.NumericEditField
        Ixx_edit     matlab.ui.control.NumericEditField
        text5        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        ok_button    matlab.ui.control.Button
        text2        matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    properties (Access = public)
        cubesatParameters; % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app,data)
            %%%Initial Euler angles
            euler = quat2eul(data.q0')*180/pi;
            
            %%%Update Inertia tensor
            app.Ixx_edit.Value = data.I(1,1);
            app.Ixy_edit.Value = data.I(2,1);
            app.Ixz_edit.Value = data.I(3,1);
            app.Iyx_edit.Value = data.I(1,2);
            app.Iyy_edit.Value = data.I(2,2);
            app.Iyz_edit.Value = data.I(3,2);
            app.Izx_edit.Value = data.I(1,3);
            app.Izy_edit.Value = data.I(2,3);
            app.Izz_edit.Value = data.I(3,3);
            %%%Update euler angles
            app.roll_edit.Value  = euler(1);
            app.pitch_edit.Value = euler(2);
            app.yaw_edit.Value   = euler(3);
            %%%Update angular rates
            app.ratex_edit.Value = data.Wo(1);
            app.ratey_edit.Value = data.Wo(2);
            app.ratez_edit.Value = data.Wo(3);
        end
        
        function newData = updateParameters(app)
            %%%Update euler angles
            q0 = eul2quat(pi/180*[app.roll_edit.Value,...
                 app.pitch_edit.Value,app.yaw_edit.Value])';
            %%%Update inertia tensor
            newData.I(1,1) = app.Ixx_edit.Value;
            newData.I(2,1) = app.Ixy_edit.Value;
            newData.I(3,1) = app.Ixz_edit.Value; 
            newData.I(1,2) = app.Iyx_edit.Value;
            newData.I(2,2) = app.Iyy_edit.Value;
            newData.I(3,2) = app.Iyz_edit.Value;
            newData.I(1,3) = app.Izx_edit.Value;
            newData.I(2,3) = app.Izy_edit.Value;
            newData.I(3,3) = app.Izz_edit.Value;
            %%%Update quaternions
            newData.q0(1) = q0(1);
            newData.q0(2) = q0(2);
            newData.q0(3) = q0(3);
            newData.q0(4) = q0(4);
            %%%Update angular rates
            newData.Wo(1) = app.ratex_edit.Value;
            newData.Wo(2) = app.ratey_edit.Value;
            newData.Wo(3) = app.ratez_edit.Value;
            %%%Identifier
            newData.i = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Button pushed function: ok_button
        function ok_buttonButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp,dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CubeSat''s Parameters';
            app.ok_button = uibutton(app.GridLayout3, 'push');
            app.ok_button.ButtonPushedFcn = createCallbackFcn(app, @ok_buttonButtonPushed, true);
            app.ok_button.Tag = 'ok_button';
            app.ok_button.Text = 'Apply';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Title = 'Inertia Tensor';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout);
            app.text3.Tag = 'text3';
            app.text3.Text = 'I =';
            app.text5 = uilabel(app.GridLayout);
            app.text5.Tag = 'text5';
            app.text5.Text = '(N/m)';
            app.Ixx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixx_edit.ValueDisplayFormat = '%.2f';
            app.Ixx_edit.Value = 1;
            app.Iyy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyy_edit.ValueDisplayFormat = '%.2f';
            app.Iyy_edit.Value = 1;
            app.Izz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izz_edit.ValueDisplayFormat = '%.2f';
            app.Izz_edit.Value = 1;
            app.Ixy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixy_edit.ValueDisplayFormat = '%.2f';
            app.Ixy_edit.Value = 1;
            app.Ixz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixz_edit.ValueDisplayFormat = '%.2f';
            app.Ixz_edit.Value = 1;
            app.Iyx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyx_edit.ValueDisplayFormat = '%.2f';
            app.Iyx_edit.Value = 1;
            app.Iyz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyz_edit.ValueDisplayFormat = '%.2f';
            app.Iyz_edit.Value = 1;
            app.Izx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izx_edit.ValueDisplayFormat = '%.2f';
            app.Izx_edit.Value = 1;
            app.Izy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izy_edit.ValueDisplayFormat = '%.2f';
            app.Izy_edit.Value = 1;
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Initial Conditions';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'X';
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Y';
            app.text9 = uilabel(app.GridLayout2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Z';
            app.text10 = uilabel(app.GridLayout2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Euler Angles (deg):';
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Angular Rate (deg/s):';
            app.pitch_edit = uieditfield(app.GridLayout2, 'numeric');
            app.pitch_edit.ValueDisplayFormat = '%.2f';
            app.pitch_edit.Value = 1;
            app.yaw_edit = uieditfield(app.GridLayout2, 'numeric');
            app.yaw_edit.ValueDisplayFormat = '%.2f';
            app.yaw_edit.Value = 1;
            app.ratex_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratex_edit.ValueDisplayFormat = '%.2f';
            app.ratex_edit.Value = 1;
            app.ratey_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratey_edit.ValueDisplayFormat = '%.2f';
            app.ratey_edit.Value = 1;
            app.ratez_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratez_edit.ValueDisplayFormat = '%.2f';
            app.ratez_edit.Value = 1;
            app.roll_edit = uieditfield(app.GridLayout2, 'numeric');
            app.roll_edit.ValueDisplayFormat = '%.2f';
            app.roll_edit.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cubesatSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end