classdef orbit_propagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        OrbitpropagationresultsLabel  matlab.ui.control.Label
        OptionsDropDown               matlab.ui.control.DropDown
        OptionsDropDownLabel          matlab.ui.control.Label
        UIAxes_z                      matlab.ui.control.UIAxes
        UIAxes_y                      matlab.ui.control.UIAxes
        UIAxes_x                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    % --- Simulation Results Storage ---
    
    callMainApp   % Handle to the main application, if needed for callbacks.
    
    % Note: Renamed from IGRCdata to ICRFdata for technical accuracy.
    ICRFdata      % (Nx3 double) Matrix storing the satellite's position vector history in the ICRF frame [m].
    
    time_vector   % (Nx1 double) Time vector for plotting, converted to hours [h].
    
    % --- Acceleration Data ---
    % Each property stores the (Nx3 double) history of the acceleration vector [m/s^2]
    % caused by a specific force model.
    accel_gravity % Acceleration due to Earth's gravity model.
    accel_drag    % Acceleration due to atmospheric drag.
    accel_sun     % Acceleration due to the Sun's third-body gravity.
    accel_moon    % Acceleration due to the Moon's third-body gravity.
    accel_srp     % Acceleration due to Solar Radiation Pressure.
    mag_field     % Magnetic field from the IGRF model in NED coordinates
end

methods (Access = private)
    
    function plot3Dorbit(app, ICRFdata)
        %plot3Dorbit Plots the 3D orbit trajectory on the app's main UI axes.
        %
        % This function clears the axes, then plots the propagated
        % orbit, a textured Earth sphere, and start/end markers.
        %
        %   INPUTS:
        %       app      - The main application object (from App Designer).
        %       ICRFdata - (Nx3 double) The position history matrix [m].
        
        import org.orekit.utils.Constants;
    
        % --- Clear and Prepare Axes ---
        % Clear the axes from any previous plots and hold for new ones.
        cla(app.UIAxes, 'reset');
        hold(app.UIAxes, 'on');
        
        % --- Plot the Trajectory ---
        % Plot the trajectory, converting position from meters to kilometers.
        plot3(app.UIAxes, ICRFdata(:,1)/1000, ICRFdata(:,2)/1000, ICRFdata(:,3)/1000, ...
              'Color', '#0072BD', 'LineWidth', 2); % A nice blue color
        
        % --- Draw a Textured Earth Sphere ---
        % Create the coordinate data for a sphere.
        [x, y, z] = sphere(50); 
        
        % Get Earth's equatorial radius in kilometers from Orekit's constants.
        earth_radius_km = Constants.WGS84_EARTH_EQUATORIAL_RADIUS / 1000;
        
        % Load the Earth texture map.
        try
            cdata = imread('earth_texture.jpg');
        catch ME
            % If the image is not found, display a warning and prepare to draw a simple sphere.
            warning('Texture file ''earth_texture.jpg'' not found. Displaying a simple blue sphere instead. Error: %s', ME.message);
            cdata = []; % Set cdata to empty to trigger the fallback.
        end
        
        % Create the surface object for the Earth.
        if ~isempty(cdata)
            % --- CORRECTED ORIENTATION ---
            % Invert the x-axis (-x) to fix the east-west mirror effect.
            % Flip the CData vertically (flipud) to fix the north-south orientation.
            surf(app.UIAxes, -x*earth_radius_km, y*earth_radius_km, z*earth_radius_km, ...
                 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', flipud(cdata));
            set(app.UIAxes, 'Color', 'k'); % Set a black background for a "space" look.
        else
            % Fallback: If no texture is available, draw a simple blue sphere.
            surf(app.UIAxes, x*earth_radius_km, y*earth_radius_km, z*earth_radius_km, ...
                 'FaceColor', 'blue', 'EdgeColor', 'none', 'FaceAlpha', 0.7);
        end
        
        % --- Plot Start and End Markers ---
        % Plot a green circle for the starting point.
        plot3(app.UIAxes, ICRFdata(1,1)/1000, ICRFdata(1,2)/1000, ICRFdata(1,3)/1000, ...
              'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8);
        % Plot a red circle for the ending point.
        plot3(app.UIAxes, ICRFdata(end,1)/1000, ICRFdata(end,2)/1000, ICRFdata(end,3)/1000, ...
              'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8);
        
        % --- Graph Configuration and Labels ---
        title(app.UIAxes, 'Propagated Orbit with Orekit');
        xlabel(app.UIAxes, 'X (km)'); 
        ylabel(app.UIAxes, 'Y (km)'); 
        zlabel(app.UIAxes, 'Z (km)');
        
        % Enable the grid and set the aspect ratio to be equal (crucial for correct orbit shape).
        grid(app.UIAxes, 'on'); 
        axis(app.UIAxes, 'equal');
        
        % Add a legend to identify the plotted elements.
        legend(app.UIAxes, 'Trajectory', 'Earth', 'Start', 'End', 'Location', 'best');
        
        % Set the camera viewing angle.
        view(app.UIAxes, 45, 25);
        
        % Release the hold on the axes.
        hold(app.UIAxes, 'off');
    end
    
    function updateInterface(app, history)
        %updateInterface Processes raw simulation data and calls plotting functions.
        % This function acts as a bridge between the simulation output and the GUI.
        % It extracts data from the history matrix, stores it in app properties,
        % and then triggers the appropriate plotting functions.
        %
        %   INPUTS:
        %       app     - The main application object.
        %       history - The raw (Nx19) simulation history matrix from the propagator.

        % --- Extract and Store Data ---
        app.time_vector   = history(:, 1) / 3600;      % Col 1: Time, converted to hours.
        app.ICRFdata      = history(:, 2:4);         % Cols 2-4: Position vector [m].
        app.accel_gravity = history(:, 5:7);         % Cols 5-7: Gravity acceleration [m/s^2].
        app.accel_drag    = history(:, 8:10);        % Cols 8-10: Drag acceleration [m/s^2].
        app.accel_sun     = history(:, 11:13);       % Cols 11-13: Sun third-body acceleration [m/s^2].
        app.accel_moon    = history(:, 14:16);       % Cols 14-16: Moon third-body acceleration [m/s^2].
        app.accel_srp     = history(:, 17:19);       % Cols 17-19: Solar Radiation Pressure acceleration [m/s^2].
        app.mag_field     = history(:, 20:22);       % Cols 20-22: Magnetic fiel [nT].

        % --- Call Plotting Functions ---
        % Plot the 3D orbit on the main axes.
        plot3Dorbit(app, app.ICRFdata);
        
        % Plot the position data components over time on the secondary axes by default.
        plotData(app, app.ICRFdata / 1000, 'ICRF Position', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Position X (km)', 'Position Y (km)', 'Position Z (km)'});
    end
    
    function plotData(app, data, title_str, xlabel_str, ylabel_str)
        %plotData Creates a 2D plot on the app's secondary UI axes.
        % A generic function to plot multi-column data against the time vector.
        %
        %   INPUTS:
        %       app        - The main application object.
        %       data       - (NxM double) The data to plot (e.g., position or acceleration).
        %       title_str  - (string) The title for the plot.
        %       xlabel_str - (string) The label for the x-axis.
        %       ylabel_str - (string) The label for the y-axis.
        
        colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30'}; % Blue, Orange, Yellow, Purple, Green


        % Clear the secondary axes before plotting.
        cla(app.UIAxes_x, 'reset');
        cla(app.UIAxes_y, 'reset');
        cla(app.UIAxes_z, 'reset');

        % Plot the data against the stored time vector.
        plot(app.UIAxes_x, app.time_vector, data(:,1), 'Color', colors{1},'LineWidth', 1.5);
        plot(app.UIAxes_y, app.time_vector, data(:,2), 'Color', colors{2},'LineWidth', 1.5);
        plot(app.UIAxes_z, app.time_vector, data(:,3), 'Color', colors{3},'LineWidth', 1.5);
        
        % Configure labels and title.
        title(app.UIAxes_x, title_str);
        xlabel(app.UIAxes_x, xlabel_str{1}); ylabel(app.UIAxes_x, ylabel_str{1});
        xlabel(app.UIAxes_y, xlabel_str{2}); ylabel(app.UIAxes_y, ylabel_str{2});
        xlabel(app.UIAxes_z, xlabel_str{3}); ylabel(app.UIAxes_z, ylabel_str{3});
        grid(app.UIAxes_x, 'on');
        grid(app.UIAxes_y, 'on');
        grid(app.UIAxes_z, 'on');
    end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Clicked callback: OptionsDropDown
        function OptionsDropDownClicked(app, event)

        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            
            if strcmp(value, 'ICRF Position')
                plotData(app, app.ICRFdata / 1000, 'ICRF Position', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Position X (km)', 'Position Y (km)', 'Position Z (km)'});
            elseif strcmp(value, 'Earth Gravity Acceleration')
                plotData(app, app.accel_gravity, 'Earth Gravity Acceleration', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Atmospheric Drag Acceleration')
                plotData(app, app.accel_drag, 'Atmospheric Drag Acceleration', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Third-Body Acceleration (Sun)')
                plotData(app, app.accel_sun, 'Third-Body Acceleration (Sun)', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Third-Body Acceleration (Moon)')
                plotData(app, app.accel_moon, 'Third-Body Acceleration (Moon)', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Solar Radiation Pressure Acceleration')
                plotData(app, app.accel_srp, 'Solar Radiation Pressure Acceleration',{'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Magnetic field (NED coordinates)')
                plotData(app, app.mag_field*1E9, 'Magnetic field (NED coordinates)',{'Time (h)', 'Time (h)', 'Time (h)'}, {'Magnetic field X (nT)', 'Magnetic field Y (nT)', 'Magnetic field Z (nT)'});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_x = uiaxes(app.GridLayout);
            title(app.UIAxes_x, 'Title')
            xlabel(app.UIAxes_x, 'X')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_y = uiaxes(app.GridLayout);
            title(app.UIAxes_y, 'Title')
            xlabel(app.UIAxes_y, 'X')
            ylabel(app.UIAxes_y, 'Y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_z = uiaxes(app.GridLayout);
            title(app.UIAxes_z, 'Title')
            xlabel(app.UIAxes_z, 'X')
            ylabel(app.UIAxes_z, 'Y')
            zlabel(app.UIAxes_z, 'Z')
            app.OptionsDropDownLabel = uilabel(app.GridLayout);
            app.OptionsDropDownLabel.Text = 'Options:';
            app.OptionsDropDown = uidropdown(app.GridLayout);
            app.OptionsDropDown.Items = {'ICRF Position', 'Earth Gravity Acceleration', 'Atmospheric Drag Acceleration', 'Third-Body Acceleration (Sun)', 'Third-Body Acceleration (Moon)', 'Solar Radiation Pressure Acceleration', 'Magnetic field (NED coordinates)'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.ClickedFcn = createCallbackFcn(app, @OptionsDropDownClicked, true);
            app.OptionsDropDown.Value = 'ICRF Position';
            app.OrbitpropagationresultsLabel = uilabel(app.GridLayout);
            app.OrbitpropagationresultsLabel.Text = 'Orbit propagation results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbit_propagation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end